classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        STOPSIMULATIONButton         matlab.ui.control.Button
        BUZZERLamp                   matlab.ui.control.Lamp
        BUZZERLampLabel              matlab.ui.control.Label
        MOTORLamp                    matlab.ui.control.Lamp
        MOTORLampLabel               matlab.ui.control.Label
        GASDETECTEDLamp              matlab.ui.control.Lamp
        GASDETECTEDLampLabel         matlab.ui.control.Label
        STATUSLamp                   matlab.ui.control.Lamp
        STATUSLampLabel              matlab.ui.control.Label
        STARTSIMULATIONButton        matlab.ui.control.Button
        GasVoltageAlarmMonitorLabel  matlab.ui.control.Label
        MANUALTRIGGERButton          matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        StopSimulationFlag = false; 
        ManualOverrideFlag = false; 
    end
    
    methods (Access = private)
        
    function IdleState(app)
        app.STATUSLamp.Color = 'green';
        app.GASDETECTEDLamp.Color = 'green';
        app.BUZZERLamp.Color = [0.5 0.5 0.5]; 
        app.MOTORLamp.Color = [0.5 0.5 0.5]; 
        app.MANUALTRIGGERButton.Text = 'MANUAL TRIGGER';
    end
    
    function VoltageTriggerState(app)
        app.STATUSLamp.Color = 'red';
        app.BUZZERLamp.Color = 'green'; 
        app.MOTORLamp.Color = 'green'; 
    end
    
    function GasTriggerState(app)
        app.STATUSLamp.Color = 'red';
        app.GASDETECTEDLamp.Color = 'red';
        app.BUZZERLamp.Color = 'green'; 
        app.MOTORLamp.Color = 'green'; 
    end
    
    function ManualTriggerState(app)
        app.STATUSLamp.Color = 'red';
        app.BUZZERLamp.Color = 'green'; 
        app.MOTORLamp.Color = 'green'; 
        app.MANUALTRIGGERButton.Text = 'TURN OFF ALARM';
    end

    function StopState(app)
        app.StopSimulationFlag = true; 
    end
    
    function ResetState(app)
        app.StopState(); 
        cla(app.UIAxes); 
        yline(app.UIAxes, 8, 'r', 'LineWidth', 2); 
        app.IdleState(); 
    end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            yline(app.UIAxes, 8, 'r', 'LineWidth', 2);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: MANUALTRIGGERButton
        function MANUALTRIGGERButtonPushed(app, event)
  if strcmp(app.MANUALTRIGGERButton.Text, 'MANUAL TRIGGER')
    app.ManualOverrideFlag = true; % بنفعل وضع المانيوال
    app.ManualTriggerState();
else
    app.ManualOverrideFlag = false; % بنلغي وضع المانيوال
    app.IdleState();
end
        end

        % Button pushed function: STARTSIMULATIONButton
        function STARTSIMULATIONButtonPushed(app, event)
           app.StopSimulationFlag = false; 
app.UIAxes.XLimMode = 'auto';
x_data = []; y_data = [];

for time_step = 1:100 
    if app.StopSimulationFlag
        break; 
    end
    
   
    if rand() > 0.2  % 80%
        simulated_V = 3 + (rand() * 4.5); % قرايات أمان بين 3 و 7.5 فولت
    else             
        simulated_V = 8.5 + (rand() * 3.5); % قرايات إنذار بين 8.5 و 12 فولت
    end
    
if rand() > 0.9   % 10% of the time
    simulated_Gas = 85 + (rand() * 15);   % 85-100, alarm
else
    simulated_Gas = 30 + (rand() * 40);   % 30-70, safe
end    
    x_data(end+1) = time_step;
    y_data(end+1) = simulated_V;
    
    plot(app.UIAxes, x_data, y_data, 'c-', 'LineWidth', 1.5);
    hold(app.UIAxes, 'on'); yline(app.UIAxes, 8, 'r', 'LineWidth', 2); hold(app.UIAxes, 'off');

    if ~app.ManualOverrideFlag
        if simulated_V > 8
            app.VoltageTriggerState();
        elseif simulated_Gas > 80 
            app.GasTriggerState();
        else
            app.IdleState();
        end


    end
    pause(0.4); 
end
        end

        % Button pushed function: STOPSIMULATIONButton
        function STOPSIMULATIONButtonPushed(app, event)
            app.StopState();
        end

        % Callback function
        function RESETSIMULATIONButtonPushed(app, event)
            app.ResetState();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'voltage')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 12];
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.MANUALTRIGGERButton = uibutton(app.UIFigure, 'push');
            app.MANUALTRIGGERButton.ButtonPushedFcn = createCallbackFcn(app, @MANUALTRIGGERButtonPushed, true);
            app.MANUALTRIGGERButton.Text = 'MANUAL TRIGGER';
            app.GasVoltageAlarmMonitorLabel = uilabel(app.UIFigure);
            app.GasVoltageAlarmMonitorLabel.Text = {'│      Gas & Voltage Alarm Monitor     │'; ''};
            app.STARTSIMULATIONButton = uibutton(app.UIFigure, 'push');
            app.STARTSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSIMULATIONButtonPushed, true);
            app.STARTSIMULATIONButton.Text = 'START SIMULATION';
            app.STATUSLampLabel = uilabel(app.UIFigure);
            app.STATUSLampLabel.Text = 'STATUS';
            app.STATUSLamp = uilamp(app.UIFigure);
            app.GASDETECTEDLampLabel = uilabel(app.UIFigure);
            app.GASDETECTEDLampLabel.Text = 'GAS DETECTED ';
            app.GASDETECTEDLamp = uilamp(app.UIFigure);
            app.MOTORLampLabel = uilabel(app.UIFigure);
            app.MOTORLampLabel.Text = 'MOTOR';
            app.MOTORLamp = uilamp(app.UIFigure);
            app.BUZZERLampLabel = uilabel(app.UIFigure);
            app.BUZZERLampLabel.Text = 'BUZZER';
            app.BUZZERLamp = uilamp(app.UIFigure);
            app.STOPSIMULATIONButton = uibutton(app.UIFigure, 'push');
            app.STOPSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STOPSIMULATIONButtonPushed, true);
            app.STOPSIMULATIONButton.Text = 'STOP SIMULATION';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end